classdef BungeeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        InfoLabel     matlab.ui.control.Label
        ExportButton  matlab.ui.control.Button
        ResetButton   matlab.ui.control.Button
        SimButton     matlab.ui.control.Button
        TEndField     matlab.ui.control.NumericEditField
        mLabel_7      matlab.ui.control.Label
        V0Field       matlab.ui.control.NumericEditField
        mLabel_6      matlab.ui.control.Label
        Y0Field       matlab.ui.control.NumericEditField
        mLabel_5      matlab.ui.control.Label
        CField        matlab.ui.control.NumericEditField
        mLabel_4      matlab.ui.control.Label
        KField        matlab.ui.control.NumericEditField
        mLabel_3      matlab.ui.control.Label
        RopeLenField  matlab.ui.control.NumericEditField
        mLabel_2      matlab.ui.control.Label
        MassField     matlab.ui.control.NumericEditField
        mLabel        matlab.ui.control.Label
        AccAxes       matlab.ui.control.UIAxes
        VelAxes       matlab.ui.control.UIAxes
        TrajAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp % Description
    end
    
    properties (Access = private)
        ModelName string = "BungeeModelOld"   % Simulink 模型名

        % 仿真结果
        t double = []
        y double = []
        v double = []
        a double = []
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            cla(app.TrajAxes);
            plot(app.TrajAxes, app.t, app.y, "LineWidth",1.5);
            grid(app.TrajAxes,"on");
            title(app.TrajAxes,"位移 y(t)");
            xlabel(app.TrajAxes,"t (s)");
            ylabel(app.TrajAxes,"y (m)");
        
            cla(app.VelAxes);
            plot(app.VelAxes, app.t, app.v, "LineWidth",1.5);
            grid(app.VelAxes,"on");
            title(app.VelAxes,"速度 v(t)");
        
            cla(app.AccAxes);
            plot(app.AccAxes, app.t, app.a, "LineWidth",1.5);
            grid(app.AccAxes,"on");
            title(app.AccAxes,"加速度 a(t)");
        end

        function updateInfo(app, L0)
            yMax = max(app.y);
            extMax = max(0, yMax - L0);
            gLoad = max(abs(app.a)) / 9.81;
        
            app.InfoLabel.Text = sprintf( ...
                "最大下落位移: %.2f m\n最大绳伸长: %.2f m\n最大过载: %.2f g", ...
                yMax, extMax, gLoad);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 默认参数（演示友好）
            app.MassField.Value    = 70;
            app.RopeLenField.Value = 30;
            app.KField.Value       = 40;
            app.CField.Value       = 5;
            app.Y0Field.Value      = 10;
            app.V0Field.Value      = 0;
            app.TEndField.Value    = 100;
        
            % 预加载模型（不弹 Simulink 窗口）
            try
                load_system(app.ModelName);
            catch
                uialert(app.UIFigure, "未找到 Simulink 模型："+app.ModelName, "模型错误");
            end
        
            % 清空图
            cla(app.TrajAxes);
            cla(app.VelAxes);
            cla(app.AccAxes);
            app.InfoLabel.Text = "等待仿真…";
        end

        % Button pushed function: SimButton
        function SimButtonPushed(app, event)
            % 1. 读取参数
            m   = app.MassField.Value;
            L0  = app.RopeLenField.Value;
            k   = app.KField.Value;
            c   = app.CField.Value;
            y0  = app.Y0Field.Value;
            v0  = app.V0Field.Value;
            tEnd = app.TEndField.Value;
            g   = 9.81;
        
            % 基本校验
            if m<=0 || L0<=0 || k<=0 || tEnd<=0
                uialert(app.UIFigure,"参数必须为正数","参数错误");
                return;
            end
        
            % 2. 构造 Simulink 输入
            mdl = char(app.ModelName);
            in = Simulink.SimulationInput(mdl);
        
            in = in.setVariable("m",  m);
            in = in.setVariable("L0", L0);
            in = in.setVariable("k",  k);
            in = in.setVariable("c",  c);
            in = in.setVariable("g",  g);
            in = in.setVariable("y0", y0);
            in = in.setVariable("v0", v0);
        
            in = in.setModelParameter("StopTime", num2str(tEnd));
            in = in.setModelParameter("SignalLogging","on");
        
            % 3. 运行仿真
            try
                out = sim(in);
            catch ME
                uialert(app.UIFigure, ME.message, "仿真失败");
                return;
            end
        
            % 4. 读取 logsout
            logs = out.logsout;
            app.y = logs.get("y").Values.Data;
            app.v = logs.get("v").Values.Data;
            app.a = logs.get("a").Values.Data;
            app.t = logs.get("y").Values.Time;
        
            % 5. 绘图
            app.updatePlots();
        
            % 6. 更新指标
            app.updateInfo(L0);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.TrajAxes);
            cla(app.VelAxes);
            cla(app.AccAxes);
            app.InfoLabel.Text = "已重置";
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.t)
                uialert(app.UIFigure,"没有仿真数据","提示");
                return;
            end
        
            T = table(app.t, app.y, app.v, app.a, ...
                'VariableNames',{'t','y','v','a'});
        
            [f,p] = uiputfile({'*.csv','CSV'}, "导出仿真数据");
            if isequal(f,0), return; end
            writetable(T, fullfile(p,f));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                app.MainApp.childClosed("bungee");
            end
            delete(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '蹦极系统仿真';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TrajAxes = uiaxes(app.UIFigure);
            title(app.TrajAxes, '位移 y(t)')
            xlabel(app.TrajAxes, 't')
            ylabel(app.TrajAxes, 'Y')
            zlabel(app.TrajAxes, 'Z')
            app.VelAxes = uiaxes(app.UIFigure);
            title(app.VelAxes, '速度 v(t)')
            xlabel(app.VelAxes, 't')
            ylabel(app.VelAxes, 'Y')
            zlabel(app.VelAxes, 'Z')
            app.AccAxes = uiaxes(app.UIFigure);
            title(app.AccAxes, '加速度 a(t)')
            xlabel(app.AccAxes, 't')
            ylabel(app.AccAxes, 'Y')
            zlabel(app.AccAxes, 'Z')
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '质量 m';
            app.MassField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = '绳长 L0';
            app.RopeLenField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel_3 = uilabel(app.UIFigure);
            app.mLabel_3.Text = '弹性系数 k';
            app.KField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel_4 = uilabel(app.UIFigure);
            app.mLabel_4.Text = '阻尼 c';
            app.CField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel_5 = uilabel(app.UIFigure);
            app.mLabel_5.Text = '初始位移 y0';
            app.Y0Field = uieditfield(app.UIFigure, 'numeric');
            app.mLabel_6 = uilabel(app.UIFigure);
            app.mLabel_6.Text = '初始速度 v0';
            app.V0Field = uieditfield(app.UIFigure, 'numeric');
            app.mLabel_7 = uilabel(app.UIFigure);
            app.mLabel_7.Text = '仿真时间';
            app.TEndField = uieditfield(app.UIFigure, 'numeric');
            app.SimButton = uibutton(app.UIFigure, 'push');
            app.SimButton.ButtonPushedFcn = createCallbackFcn(app, @SimButtonPushed, true);
            app.SimButton.Text = '开始仿真';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '重置';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = '导出数据';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'InfoLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BungeeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end