classdef CustomApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        CopyButton          matlab.ui.control.Button
        ExportButton        matlab.ui.control.Button
        ClearButton         matlab.ui.control.Button
        GenerateButton      matlab.ui.control.Button
        UniqueSeedCheckBox  matlab.ui.control.CheckBox
        CountSpinner        matlab.ui.control.Spinner
        Label               matlab.ui.control.Label
        LotteryDropDown     matlab.ui.control.DropDown
        DropDownLabel       matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        InfoLabel           matlab.ui.control.Label
        TicketTable         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        MainApp % Description
    end
    
    properties (Access = private)
        History table = table()  % Description
    end
    
    methods (Access = private)
        
        function initTableForLottery(app, typ)
            typ = string(typ);
            app.History = table();
        
            switch typ
                case "双色球"
                    app.TicketTable.ColumnName = {'Red1','Red2','Red3','Red4','Red5','Red6','Blue'};
                    app.InfoLabel.Text = "规则：红球 6 个（1-33 不重复）+ 蓝球 1 个（1-16）。";
                case "大乐透"
                    app.TicketTable.ColumnName = {'Front1','Front2','Front3','Front4','Front5','Back1','Back2'};
                    app.InfoLabel.Text = "规则：前区 5 个（1-35 不重复）+ 后区 2 个（1-12 不重复）。";
                case "3D"
                    app.TicketTable.ColumnName = {'D1','D2','D3'};
                    app.InfoLabel.Text = "规则：3 位数字（0-9 可重复）。";
            end
        
            app.TicketTable.Data = app.History;
        end

        function T = generateTickets(app, typ, n)
            typ = string(typ);

            switch typ
                case "双色球"
                    reds = zeros(n,6);
                    blues = zeros(n,1);
                    for i=1:n
                        r = sort(randperm(33,6));
                        b = randi(16,1,1);
                        reds(i,:) = r;
                        blues(i) = b;
                    end
                    T = array2table([reds blues], ...
                        "VariableNames", {'Red1','Red2','Red3','Red4','Red5','Red6','Blue'});

                case "大乐透"
                    front = zeros(n,5);
                    back  = zeros(n,2);
                    for i=1:n
                        f = sort(randperm(35,5));
                        b = sort(randperm(12,2));
                        front(i,:) = f;
                        back(i,:)  = b;
                    end
                    T = array2table([front back], ...
                        "VariableNames", {'Front1','Front2','Front3','Front4','Front5','Back1','Back2'});

                case "3D"
                    d = randi([0 9], n, 3);
                    T = array2table(d, "VariableNames", {'D1','D2','D3'});

                otherwise
                    T = table();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LotteryDropDown.Items = {"双色球","大乐透","3D"};
            app.LotteryDropDown.Value = "双色球";
        
            app.CountSpinner.Limits = [1 10];
            app.CountSpinner.Value = 5;
        
            app.UniqueSeedCheckBox.Value = true;
        
            app.initTableForLottery(app.LotteryDropDown.Value);
        end

        % Drop down opening function: LotteryDropDown
        function LotteryDropDownOpening(app, event)
            app.initTableForLottery(app.LotteryDropDown.Value);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.History = table();
            app.TicketTable.Data = app.History;
            app.InfoLabel.Text = "已清空。";
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if isempty(app.History) || height(app.History)==0
                uialert(app.UIFigure,"没有可导出的数据，请先生成号码。","提示");
                return;
            end
        
            [f,p] = uiputfile( ...
                {'*.csv','CSV (*.csv)';'*.xlsx','Excel (*.xlsx)'}, ...
                "导出选号结果");
            if isequal(f,0), return; end
        
            fp = fullfile(p,f);
            [~,~,ext] = fileparts(fp);
        
            try
                if strcmpi(ext,'.csv')
                    writetable(app.History, fp);
                else
                    writetable(app.History, fp, "FileType","spreadsheet");
                end
                app.InfoLabel.Text = "已导出：" + string(fp);
            catch ME
                uialert(app.UIFigure, ME.message, "导出失败");
            end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            if isempty(app.History) || height(app.History)==0
                uialert(app.UIFigure,"没有数据可复制。","提示");
                return;
            end
        
            % 把表转成简洁文本
            txt = evalc("disp(app.History)");
            clipboard("copy", txt);
            app.InfoLabel.Text = "已复制到剪贴板。";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                app.MainApp.childClosed("custom");
            end
            delete(app);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            if app.UniqueSeedCheckBox.Value
                rng('shuffle'); % 每次不同
            end

            n = app.CountSpinner.Value;
            typ = string(app.LotteryDropDown.Value);

            newT = app.generateTickets(typ, n);

            % 追加历史
            if isempty(app.History) || height(app.History)==0
                app.History = newT;
            else
                % 如果列不一致（换彩种），直接覆盖为新彩种历史更合理
                if ~isequal(app.History.Properties.VariableNames, newT.Properties.VariableNames)
                    app.History = newT;
                else
                    app.History = [app.History; newT];
                end
            end

            app.TicketTable.Data = app.History;
            app.InfoLabel.Text = "已生成 " + n + " 注，累计 " + height(app.History) + " 注。";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '彩票系统';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = '彩票类型';
            app.LotteryDropDown = uidropdown(app.LeftPanel);
            app.LotteryDropDown.Items = {'双色球', '大乐透', '3D'};
            app.LotteryDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LotteryDropDownOpening, true);
            app.LotteryDropDown.Value = '双色球';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '购买注数';
            app.CountSpinner = uispinner(app.LeftPanel);
            app.CountSpinner.Limits = [1 10];
            app.CountSpinner.Value = 5;
            app.UniqueSeedCheckBox = uicheckbox(app.LeftPanel);
            app.UniqueSeedCheckBox.Text = '每次随机（shuffle）';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = '生成号码';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = '清空历史';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = '导出 CSV/Excel';
            app.CopyButton = uibutton(app.LeftPanel, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = '复制当前表格为文本到剪贴板';
            app.RightPanel = uipanel(app.GridLayout);
            app.TicketTable = uitable(app.RightPanel);
            app.TicketTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TicketTable.RowName = {};
            app.InfoLabel = uilabel(app.RightPanel);
            app.InfoLabel.Text = {'双色球：红球 6（1–33 不重复）+ 蓝球 1（1–16）'; '大乐透：前区 5（1–35 不重复）+ 后区 2（1–12 不重复）'; '3D：3 位数字（0–9 可重复）'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end