classdef GradeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        NameField         matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        StudentIDField    matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        ResetButton       matlab.ui.control.Button
        QueryButton       matlab.ui.control.Button
        CenterPanel       matlab.ui.container.Panel
        SaveButton        matlab.ui.control.Button
        DeleteRowButton   matlab.ui.control.Button
        AddRowButton      matlab.ui.control.Button
        LoadButton        matlab.ui.control.Button
        SaveAsButton      matlab.ui.control.Button
        GradeTable        matlab.ui.control.Table
        RightPanel        matlab.ui.container.Panel
        ExportPlotButton  matlab.ui.control.Button
        PlotButton        matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        MainApp % Description
    end

    properties (Access = private)
        RawT table
        ViewT table
        SelectedRow (1,1) double = NaN
        CurrentFilePath string = ""   % 当前加载文件的完整路径
        IsDirty (1,1) logical = false % 数据是否被修改过（编辑/新增/删除）
    end

    methods (Access = private)

        function applyView(app, T)
            app.ViewT = T;
            app.GradeTable.Data = app.ViewT;
        end
    
        function T = normalizeGradeTable(app, T)
            vn0 = string(T.Properties.VariableNames);
    
            % 统一做清洗：去空格/去BOM/转小写，方便匹配
            vnKey = lower(erase(vn0, [" ", char(65279), char(160)]));
        
            % 允许多种可能的表头写法（中英都支持）
            map = struct();
            map.StudentID = ["学号","studentid","student_id","id","sid"];
            map.Name      = ["姓名","name","studentname"];
            map.Chinese   = ["语文","chinese","yuwen"];
            map.Math      = ["数学","math","shuxue"];
            map.English   = ["英语","english","yingyu"];
            map.Physics   = ["物理","physics","wuli"];
        
            % 在原表中寻找每个标准列
            standard = ["StudentID","Name","Chinese","Math","English","Physics"];
            newNames = strings(size(vn0));
            newNames(:) = vn0;  % 默认不改
        
            % 标记找到的列索引
            idxFound = containers.Map();
        
            % 按映射表查找
            fields = fieldnames(map);
            for i = 1:numel(fields)
                std = fields{i};
                candidates = map.(std);
        
                hit = [];
                for c = candidates
                    k = lower(erase(string(c), [" ", char(65279), char(160)]));
                    j = find(vnKey == k, 1);
                    if ~isempty(j)
                        hit = j; break;
                    end
                end
        
                if ~isempty(hit)
                    newNames(hit) = std;
                    idxFound(std) = hit;
                end
            end
        
            % 应用新列名
            T.Properties.VariableNames = cellstr(newNames);
        
            % 必要列检查
            if ~ismember("StudentID", T.Properties.VariableNames)
                error("成绩数据必须包含列：StudentID（可用表头：学号 / StudentID / student_id 等）");
            end
            if ~ismember("Name", T.Properties.VariableNames)
                error("成绩数据必须包含列：Name（可用表头：姓名 / Name 等）");
            end
        
            % 类型规范化
            T.StudentID = string(T.StudentID);
            T.Name      = string(T.Name);
        
            subjects = ["Chinese","Math","English","Physics"];
            for s = subjects
                if ~ismember(s, T.Properties.VariableNames)
                    T.(s) = nan(height(T),1);
                end
                T.(s) = double(T.(s));
            end
        
            % 固定列顺序
            T = T(:, ["StudentID","Name",subjects]);
        end
    
        function plotStats(app, T)
            cla(app.UIAxes);
        
            if isempty(T) || height(T)==0
                text(app.UIAxes, 0.1, 0.5, "当前无数据", "FontSize", 14);
                axis(app.UIAxes, "off");
                return;
            end
        
            % 代码层（英文列名）
            subjects = ["Chinese","Math","English","Physics"];
            % 显示层（中文）
            labelsCN = ["语文","数学","英语","物理"];
        
            avg = nan(1,numel(subjects));
            mx  = nan(1,numel(subjects));
            mn  = nan(1,numel(subjects));
        
            for i = 1:numel(subjects)
                s = subjects(i);
                v = T.(s);
        
                % 允许 NaN（缺考/缺失）
                v = v(~isnan(v));
                if ~isempty(v)
                    avg(i) = mean(v);
                    mx(i)  = max(v);
                    mn(i)  = min(v);
                end
            end
        
            bar(app.UIAxes, avg);
            grid(app.UIAxes, "on");
            xticks(app.UIAxes, 1:numel(labelsCN));
            xticklabels(app.UIAxes, labelsCN);
            ylabel(app.UIAxes, "平均分");
            title(app.UIAxes, sprintf("人数=%d | 各科平均/最高/最低", height(T)));
        
            for i = 1:numel(subjects)
                if ~isnan(avg(i))
                    txt = sprintf("max %.0f\nmin %.0f", mx(i), mn(i));
                    text(app.UIAxes, i, avg(i), txt, ...
                        "VerticalAlignment","bottom", ...
                        "HorizontalAlignment","center", ...
                        "FontSize", 9);
                end
            end
        end

        function [ok, msg] = validateGrades(app, T)
            ok = true;
            msg = "";
        
            if isempty(T) || height(T)==0
                ok = false;
                msg = "当前没有数据。";
                return;
            end
        
            % 必须列存在（防止误传）
            mustVars = ["StudentID","Name","Chinese","Math","English","Physics"];
            for v = mustVars
                if ~ismember(v, string(T.Properties.VariableNames))
                    ok = false;
                    msg = "数据缺少必要列：" + v;
                    return;
                end
            end
        
            % 统一类型（避免 string/cell 混乱）
            T.StudentID = strtrim(string(T.StudentID));
            T.Name      = strtrim(string(T.Name));
        
            % 1) 必填：学号/姓名
            badID = find(T.StudentID == "" | ismissing(T.StudentID), 1);
            if ~isempty(badID)
                ok = false;
                msg = sprintf("第 %d 行学号为空，请填写 StudentID。", badID);
                return;
            end
        
            badName = find(T.Name == "" | ismissing(T.Name), 1);
            if ~isempty(badName)
                ok = false;
                msg = sprintf("第 %d 行姓名为空，请填写 Name。", badName);
                return;
            end
        
            % 2) 学号唯一
            [u, ~, ic] = unique(T.StudentID, "stable");
            if numel(u) < height(T)
                counts = accumarray(ic, 1);
                dupID = u(find(counts > 1, 1));
                rows = find(T.StudentID == dupID);
                ok = false;
                msg = sprintf("学号重复：%s（出现在第 %s 行）。", dupID, strjoin(string(rows)', ", "));
                return;
            end
        
            % 3) 成绩范围校验：允许 NaN（未填/缺考），但非 NaN 必须 0~100
            subjects = ["Chinese","Math","English","Physics"];
            for s = subjects
                v = double(T.(s));
                bad = find(~isnan(v) & (v < 0 | v > 100), 1);
                if ~isempty(bad)
                    ok = false;
                    msg = sprintf("第 %d 行 %s 成绩超出范围（0~100）。", bad, s);
                    return;
                end
                % 若用户输入了非数值导致变 NaN，这里你也可以加“强制提示”
                % 但目前允许 NaN 作为未填，不强制报错
            end
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RawT = table(string.empty, string.empty, ...
            nan(0,1), nan(0,1), nan(0,1), nan(0,1), ...
            'VariableNames', {'StudentID','Name','Chinese','Math','English','Physics'});
    
            app.applyView(app.RawT);
        
            app.StudentIDField.Value = "";
            app.NameField.Value = "";
        
            cla(app.UIAxes);
            title(app.UIAxes, "统计信息");
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [f,p] = uigetfile({'*.xlsx;*.xls;*.csv','数据文件 (*.xlsx,*.xls,*.csv)'}, "选择成绩文件");
            if isequal(f,0), return; end
            fp = fullfile(p,f);
    
            try
                opts = detectImportOptions(fp, "TextType","string");
                opts.VariableNamingRule = "preserve";   % ✅ 不要自动改表头
                T = readtable(fp, opts);
                T = app.normalizeGradeTable(T);
                app.CurrentFilePath = string(fp);
                app.IsDirty = false;
    
                app.RawT = T;
                app.applyView(app.RawT);
                app.plotStats(app.ViewT);
    
            catch ME
                uialert(app.UIFigure, ME.message, "加载失败");
            end
        end

        % Button pushed function: QueryButton
        function QueryButtonPushed(app, event)
            if isempty(app.RawT) || height(app.RawT)==0
                uialert(app.UIFigure, "请先加载数据。", "提示");
                return;
            end
        
            sid = strtrim(string(app.StudentIDField.Value));
            nm  = strtrim(string(app.NameField.Value));
        
            T = app.RawT;
        
            if sid ~= ""
                T = T(contains(T.StudentID, sid), :);
            end
            if nm ~= ""
                T = T(contains(T.Name, nm), :);
            end
        
            app.applyView(T);
            app.plotStats(app.ViewT);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if isempty(app.ViewT) || height(app.ViewT)==0
                uialert(app.UIFigure, "当前没有可绘制的数据。", "提示");
                return;
            end
        
            [ok, msg] = app.validateGrades(app.ViewT);
            if ~ok
                uialert(app.UIFigure, "绘图前校验失败：" + msg, "无法绘图");
                return;
            end
        
            app.plotStats(app.ViewT);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.plotStats(app.ViewT);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            if ~isempty(app.ViewT) && height(app.ViewT) > 0
                Texport = app.ViewT;
            elseif ~isempty(app.RawT) && height(app.RawT) > 0
                Texport = app.RawT;
            else
                uialert(app.UIFigure, "没有可导出的数据，请先加载或查询。", "提示");
                return;
            end
        
            % ✅ 导出前校验
            [ok, msg] = app.validateGrades(Texport);
            if ~ok
                uialert(app.UIFigure, "导出前校验失败：" + msg, "无法导出");
                return;
            end
        
            [f,p] = uiputfile({'*.xlsx','Excel (*.xlsx)';'*.csv','CSV (*.csv)'}, "导出数据");
            if isequal(f,0), return; end
            fp = fullfile(p,f);
        
            try
                [~,~,ext] = fileparts(fp);
                if strcmpi(ext,'.csv')
                    writetable(Texport, fp);
                else
                    writetable(Texport, fp, "FileType","spreadsheet");
                end
                app.CurrentFilePath = string(fp);
                app.IsDirty = false;
            catch ME
                uialert(app.UIFigure, ME.message, "导出失败");
            end

        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
        [f,p] = uiputfile({'*.png';'*.jpg'}, "导出图像");
        if isequal(f,0), return; end
        exportgraphics(app.UIAxes, fullfile(p,f));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                app.MainApp.childClosed("grade");
            end
    
            if app.IsDirty
                choice = uiconfirm(app.UIFigure, "数据已修改但尚未保存，是否保存？", "未保存提示", ...
                    "Options", ["保存","不保存","取消"], "DefaultOption", 1, "CancelOption", 3);
            
                if choice == "取消"
                    return;
                elseif choice == "保存"
                    app.SaveButtonPushed([]);
                    if app.IsDirty
                        % 若保存失败或用户取消另存为，则不关闭
                        return;
                    end
                end
            end
            delete(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            % 取当前显示表作为基础（更符合用户看到什么改什么）
            T = app.ViewT;
            if isempty(T)
                % 如果还没初始化过 ViewT，就创建空表
                T = table(string.empty, string.empty, nan(0,1), nan(0,1), nan(0,1), nan(0,1), ...
                    'VariableNames', {'StudentID','Name','Chinese','Math','English','Physics'});
            end
        
            newRow = table("", "", 0, 0, 0, 0, ...
                'VariableNames', {'StudentID','Name','Chinese','Math','English','Physics'});
        
            T = [T; newRow];

            app.IsDirty = true;

        
            app.applyView(T);
            app.plotStats(app.ViewT);
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
            if isempty(app.ViewT) || height(app.ViewT)==0
                uialert(app.UIFigure, "当前没有数据可删除。", "提示");
                return;
            end
            if isnan(app.SelectedRow) || app.SelectedRow < 1 || app.SelectedRow > height(app.ViewT)
                uialert(app.UIFigure, "请先在表格中选中要删除的行。", "提示");
                return;
            end
        
            choice = uiconfirm(app.UIFigure, ...
                sprintf("确认删除第 %d 行数据吗？", app.SelectedRow), ...
                "确认删除", ...
                "Options", ["删除","取消"], ...
                "DefaultOption", 2, ...
                "CancelOption", 2);
        
            if choice ~= "删除"
                return;
            end
        
            T = app.ViewT;
            T(app.SelectedRow, :) = [];
            app.IsDirty = true;


            app.SelectedRow = NaN;
            app.applyView(T);
            app.plotStats(app.ViewT);
        end

        % Cell edit callback: GradeTable
        function GradeTableCellEdit(app, event)
            T = app.GradeTable.Data;

            if ~istable(T)
                T = cell2table(T, 'VariableNames', {'StudentID','Name','Chinese','Math','English','Physics'});
            end
        
            % 类型标准化
            T.StudentID = strtrim(string(T.StudentID));
            T.Name      = strtrim(string(T.Name));
        
            subjects = ["Chinese","Math","English","Physics"];
            for s = subjects
                T.(s) = double(T.(s));
            end
        
            % ✅ 校验
            [ok, msg] = app.validateGrades(T);
            if ~ok
                uialert(app.UIFigure, msg, "数据校验失败");
                % 不直接覆盖 RawT/ViewT，避免把错误数据写入缓存
                % 你也可以选择：回滚到上一次 ViewT（更严格）
                app.GradeTable.Data = app.ViewT;
                return;
            end

            app.IsDirty = true;
        
            app.ViewT = T;
            app.RawT  = T;
        
            app.plotStats(app.ViewT);
            
        end

        % Cell selection callback: GradeTable
        function GradeTableCellSelection(app, event)
            % event.Indices: [row col]，可能多选
            if isempty(event.Indices)
                app.SelectedRow = NaN;
                return;
            end
            app.SelectedRow = event.Indices(1,1); % 取第一个选中的行
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % 1) 取当前数据源：优先 ViewT
            if ~isempty(app.ViewT) && height(app.ViewT) > 0
                Tsave = app.ViewT;
            elseif ~isempty(app.RawT) && height(app.RawT) > 0
                Tsave = app.RawT;
            else
                uialert(app.UIFigure, "没有可保存的数据。", "提示");
                return;
            end
        
            % 2) 保存前校验
            [ok, msg] = app.validateGrades(Tsave);
            if ~ok
                uialert(app.UIFigure, "保存失败（校验未通过）：" + msg, "无法保存");
                return;
            end
        
            % 3) 如果没有加载过文件，就退化为“另存为”
            if app.CurrentFilePath == "" || ismissing(app.CurrentFilePath)
                % 直接调用你已有的另存为逻辑
                app.SaveAsButtonPushed([]);
                return;
            end
        
            % 4) 原地写回
            try
                fp = char(app.CurrentFilePath);
                [~,~,ext] = fileparts(fp);
        
                if strcmpi(ext, ".csv")
                    writetable(Tsave, fp);
                else
                    writetable(Tsave, fp, "FileType","spreadsheet");
                end
        
                app.IsDirty = false;
                uialert(app.UIFigure, "保存成功：" + string(fp), "保存");
        
            catch ME
                uialert(app.UIFigure, ME.message, "保存失败");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {568, 568, 568};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {568, 568};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {218, '1x', 410};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '成绩查询';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.QueryButton = uibutton(app.LeftPanel, 'push');
            app.QueryButton.ButtonPushedFcn = createCallbackFcn(app, @QueryButtonPushed, true);
            app.QueryButton.Text = '查询';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = '重置条件';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '学号';
            app.StudentIDField = uieditfield(app.LeftPanel, 'text');
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '姓名';
            app.NameField = uieditfield(app.LeftPanel, 'text');
            app.CenterPanel = uipanel(app.GridLayout);
            app.GradeTable = uitable(app.CenterPanel);
            app.GradeTable.ColumnName = {'学号'; '姓名'; '语文'; '数学'; '英语'; '物理'};
            app.GradeTable.RowName = {};
            app.GradeTable.ColumnSortable = [false false true true true true];
            app.GradeTable.ColumnEditable = true;
            app.GradeTable.CellEditCallback = createCallbackFcn(app, @GradeTableCellEdit, true);
            app.GradeTable.CellSelectionCallback = createCallbackFcn(app, @GradeTableCellSelection, true);
            app.SaveAsButton = uibutton(app.CenterPanel, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = '导出数据';
            app.LoadButton = uibutton(app.CenterPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '加载数据';
            app.AddRowButton = uibutton(app.CenterPanel, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = '+';
            app.DeleteRowButton = uibutton(app.CenterPanel, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = '-';
            app.SaveButton = uibutton(app.CenterPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '保存';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '人数/平均分/最高分/最低分')
            xlabel(app.UIAxes, '科目')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = '生成图表';
            app.ExportPlotButton = uibutton(app.RightPanel, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = '导出图像';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GradeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end