classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        HTML      matlab.ui.control.HTML
    end

    
    properties (Access = private)
        PlotWin
        GradeWin
        BungeeWin
        CustomWin
        % Description
    end
    methods (Access = private)
        function sendToHtml(app, action, payload)
            if nargin < 3, payload = struct(); end
            if isempty(app.HTML) || ~isvalid(app.HTML), return; end
        
            app.HTML.Data = struct("action", char(string(action)), "payload", payload);
            drawnow limitrate;
        end

        function openChild(app, which)
            switch which
                case "plot"
                    if isempty(app.PlotWin) || ~isvalid(app.PlotWin)
                        app.PlotWin = PlotApp;
                        app.PlotWin.UIFigure.CloseRequestFcn = @(src,evt) app.childClosed("plot");
                    else
                        figure(app.PlotWin.UIFigure);
                    end
        
                case "grade"
                    if isempty(app.GradeWin) || ~isvalid(app.GradeWin)
                        app.GradeWin = GradeApp;
                        app.GradeWin.UIFigure.CloseRequestFcn = @(src,evt) app.childClosed("grade");
                    else
                        figure(app.GradeWin.UIFigure);
                    end
        
                case "bungee"
                    if isempty(app.BungeeWin) || ~isvalid(app.BungeeWin)
                        app.BungeeWin = BungeeApp;
                        app.BungeeWin.UIFigure.CloseRequestFcn = @(src,evt) app.childClosed("bungee");
                    else
                        figure(app.BungeeWin.UIFigure);
                    end
        
                case "custom"
                    if isempty(app.CustomWin) || ~isvalid(app.CustomWin)
                        app.CustomWin = CustomApp;
                        app.CustomWin.UIFigure.CloseRequestFcn = @(src,evt) app.childClosed("custom");
                    else
                        figure(app.CustomWin.UIFigure);
                    end
            end
        end

        function childClosed(app, which)
            switch which
                case "plot"
                    if ~isempty(app.PlotWin) && isvalid(app.PlotWin), delete(app.PlotWin); end
                case "grade"
                    if ~isempty(app.GradeWin) && isvalid(app.GradeWin), delete(app.GradeWin); end
                case "bungee"
                    if ~isempty(app.BungeeWin) && isvalid(app.BungeeWin), delete(app.BungeeWin); end
                case "custom"
                    if ~isempty(app.CustomWin) && isvalid(app.CustomWin), delete(app.CustomWin); end
            end
            app.syncMainStateToHtml();
        end
        
        function syncMainStateToHtml(app)
            s = struct( ...
                "plotOpen",   (~isempty(app.PlotWin)   && isvalid(app.PlotWin)), ...
                "gradeOpen",  (~isempty(app.GradeWin)  && isvalid(app.GradeWin)), ...
                "bungeeOpen", (~isempty(app.BungeeWin) && isvalid(app.BungeeWin)), ...
                "customOpen", (~isempty(app.CustomWin) && isvalid(app.CustomWin)) ...
            );
            app.sendToHtml("setState", s);
        end

        function log(app, msg, data)
            ts = char(datetime("now","Format","HH:mm:ss.SSS"));
            if nargin < 3
                fprintf("[MainApp %s] %s\n", ts, msg);
            else
                fprintf("[MainApp %s] %s\n", ts, msg);
                try
                    disp(data);
                catch
                    fprintf("  (data 无法显示)\n");
                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 给 HTML 发一个“已连接/初始化”消息（可选）
            app.sendToHtml("toast", struct("text","MATLAB 已连接"));
        end

        % Event received function: HTML
        function HTMLEventReceived(app, event)
             % ===== 日志：回调触发 =====
            app.log("HTMLEventReceived 触发");
            app.log("HTMLEventName = " + string(event.HTMLEventName));
            app.log("HTMLEventData =", event.HTMLEventData);
        
            % ===== 1) 校验事件名 =====
            if string(event.HTMLEventName) ~= "ui"
                app.log("事件名不是 ui，忽略");
                return;
            end
        
            % ===== 2) 校验数据结构 =====
            data = event.HTMLEventData;
            if isempty(data)
                app.log("HTMLEventData 为空，返回");
                return;
            end
            if ~isstruct(data)
                app.log("HTMLEventData 不是 struct（类型=" + string(class(data)) + "），返回", data);
                return;
            end
            if ~isfield(data, "action")
                app.log("HTMLEventData 缺少 action 字段，返回", data);
                return;
            end
        
            action = string(data.action);
            payload = struct();
            if isfield(data, "payload") && ~isempty(data.payload)
                payload = data.payload;
            end
        
            app.log("解析成功：action=" + action);
            app.log("payload =", payload);
        
            % ===== 3) 分发动作 =====
            switch action
                case "openChild"
                    if ~isfield(payload, "which")
                        app.log("openChild 缺少 payload.which，返回", payload);
                        return;
                    end
        
                    which = string(payload.which);
                    app.log("准备打开子窗口：" + which);
        
                    try
                        app.openChild(which);
                        app.log("openChild 执行完成：" + which);
                    catch ME
                        app.log("openChild 执行报错：" + string(ME.message));
                        uialert(app.UIFigure, ME.message, "openChild 错误");
                        return;
                    end
        
                    app.syncMainStateToHtml();
                    app.log("已回传 setState 给 HTML");
        
                otherwise
                    app.log("未知 action：" + action);
                    app.sendToHtml("toast", struct("text","未知 action: " + action));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '主页面';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = './MainPage.html';
            app.HTML.HTMLEventReceivedFcn = createCallbackFcn(app, @HTMLEventReceived, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end