classdef PlotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Spinner             matlab.ui.control.Spinner
        Label_3             matlab.ui.control.Label
        ShowMarkerCheckBox  matlab.ui.control.CheckBox
        MarkerDropDown      matlab.ui.control.DropDown
        DropDown_2Label     matlab.ui.control.Label
        ColorPicker         matlab.ui.control.ColorPicker
        Label_2             matlab.ui.control.Label
        NField              matlab.ui.control.NumericEditField
        Label               matlab.ui.control.Label
        XmaxField           matlab.ui.control.NumericEditField
        xLabel_2            matlab.ui.control.Label
        XminField           matlab.ui.control.NumericEditField
        xLabel              matlab.ui.control.Label
        ExportButton        matlab.ui.control.Button
        ClearButton         matlab.ui.control.Button
        PlotButton          matlab.ui.control.Button
        P3Field             matlab.ui.control.NumericEditField
        EditFieldLabel_3    matlab.ui.control.Label
        P2Field             matlab.ui.control.NumericEditField
        EditFieldLabel_2    matlab.ui.control.Label
        P1Field             matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        DropDownLabel       matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MainApp % Description
    end
    
    properties (Access = private)
        CurveColor (1,3) double = [0 0.4470 0.7410]; % Description
        LastLine matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty

        % 数据点设置（默认值）
        ShowMarker (1,1) logical = true
        MarkerSymbol (1,1) string = "o"
        MarkerSize (1,1) double = 6
        MarkerStep (1,1) double = 5
        FillMarker (1,1) logical = true
    end
    
    methods (Access = private)
        
        function updateParamLabels(app)
             f = string(app.DropDown.Value);
        
            switch f
                case "二次函数"
                    % 用现有的三个 Label 作为提示文本
                    app.EditFieldLabel.Text   = "a（x^2系数）";
                    app.EditFieldLabel_2.Text = "b（x系数）";
                    app.EditFieldLabel_3.Text = "c（常数项）";
        
                    % 可选：给输入框加 Tooltip（鼠标悬停提示）
                    app.P1Field.Tooltip = "y = a*x^2 + b*x + c";
                    app.P2Field.Tooltip = "y = a*x^2 + b*x + c";
                    app.P3Field.Tooltip = "y = a*x^2 + b*x + c";
        
                case "高斯函数"
                    app.EditFieldLabel.Text   = "A（峰值/振幅）";
                    app.EditFieldLabel_2.Text = "μ（中心位置）";
                    app.EditFieldLabel_3.Text = "σ（宽度，>0）";
        
                    app.P1Field.Tooltip = "y = A*exp(-(x-μ)^2/(2σ^2))";
                    app.P2Field.Tooltip = "y = A*exp(-(x-μ)^2/(2σ^2))";
                    app.P3Field.Tooltip = "σ 必须 > 0";
            end
        end

        function applyMarkerStyle(app)
            if isempty(app.LastLine) || ~isvalid(app.LastLine)
                return;
            end
        
            % 不显示点
            if ~app.ShowMarker || app.MarkerSymbol == "none"
                app.LastLine.Marker = "none";
                app.LastLine.MarkerIndices = [];
                return;
            end
        
            % 显示点：形状/大小/密度
            app.LastLine.Marker = char(app.MarkerSymbol);
            app.LastLine.MarkerSize = app.MarkerSize;
        
            N = numel(app.LastLine.XData);
            K = max(1, round(app.MarkerStep));
            app.LastLine.MarkerIndices = 1:K:N;
        
            % ✅ 点颜色与线条颜色同步
            app.LastLine.MarkerEdgeColor = app.CurveColor;
        
            if app.FillMarker
                app.LastLine.MarkerFaceColor = app.CurveColor;
            else
                app.LastLine.MarkerFaceColor = "none";
            end
        end

        function sym = markerTextToSymbol(app, txt)
                t = string(txt);
            
                if startsWith(t, "无")
                    sym = "none"; return;
                end
            
                if contains(t, "o"), sym = "o"; return; end
                if contains(t, "s"), sym = "s"; return; end
                if contains(t, "^"), sym = "^"; return; end
                if contains(t, "v"), sym = "v"; return; end
                if contains(t, "<"), sym = "<"; return; end
                if contains(t, ">"), sym = ">"; return; end
                if contains(t, "d"), sym = "d"; return; end
                if contains(t, "p"), sym = "p"; return; end
                if contains(t, "h"), sym = "h"; return; end
                if contains(t, "*"), sym = "*"; return; end
                if contains(t, "x"), sym = "x"; return; end
                if contains(t, "+"), sym = "+"; return; end
            
                sym = "none";
        end

        function idx = makeMarkerIndices(app, nPoints, step)
            if nPoints <= 0
                idx = [];
                return;
            end
        
            k = round(step);
            if ~isfinite(k) || k < 1
                k = 1;
            end
        
            idx = 1:k:nPoints;
        end
        
        function syncMarkerSettingsFromUI(app)
            % 是否显示点
            app.ShowMarker = logical(app.ShowMarkerCheckBox.Value);
        
            % 形状：把下拉文字映射成符号
            app.MarkerSymbol = app.markerTextToSymbol(app.MarkerDropDown.Value);
        
            % 大小：来自 Spinner
            app.MarkerSize = app.Spinner.Value;
            if ~isfinite(app.MarkerSize) || app.MarkerSize < 1
                app.MarkerSize = 6;
                app.Spinner.Value = app.MarkerSize;
            end
        
            % 你当前没有 MarkerStep 的 UI，就用默认属性值即可
            % app.MarkerStep 保持不变（默认 5）
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 这里可以设置默认值、标题等
            if isempty(app.NField.Value)
                app.NField.Value = 200;
            end

            app.updateParamLabels();

            % —— 初始化颜色（从 ColorPicker 读）——
            if isprop(app,"ColorPicker") && ~isempty(app.ColorPicker)
                app.CurveColor = app.ColorPicker.Value;
            end

            % 初始化点相关控件的默认值（与你的属性一致）
            app.ShowMarkerCheckBox.Value = app.ShowMarker;
            app.MarkerDropDown.Value = "无";          % 默认不显示点，你也可以改成 'o 圆形'
            app.Spinner.Value = app.MarkerSize;       % 点大小
            
            % 同步一次（防止属性与UI不一致）
            app.syncMarkerSettingsFromUI();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            xmin = app.XminField.Value;
            xmax = app.XmaxField.Value;
            N    = app.NField.Value;
            x = linspace(xmin, xmax, N);
        
            p1 = app.P1Field.Value;
            p2 = app.P2Field.Value;
            p3 = app.P3Field.Value;
        
            switch string(app.DropDown.Value)
                case "二次函数"
                    y = p1*x.^2 + p2*x + p3;
                case "高斯函数"
                    if string(app.DropDown.Value) == "高斯函数" && p3 <= 0
                        uialert(app.UIFigure, "σ 必须大于 0", "参数错误");
                        return;
                    end
                    A=p1; mu=p2; sigma=p3;
                    y = A * exp(-(x-mu).^2./(2*sigma^2));
            end
        
            cla(app.UIAxes);

            app.LastLine = plot(app.UIAxes, x, y, ...
                "LineWidth", 2, ...
                "Color", app.CurveColor);
            
            grid(app.UIAxes,"on");
            
            % 绘完后把UI设置同步到属性，再应用点样式
            app.syncMarkerSettingsFromUI();
            app.applyMarkerStyle();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [f,p] = uiputfile( ...
                {'*.png','PNG Image (*.png)'; ...
                '*.jpg','JPEG Image (*.jpg)'}, ...
                '导出图像');
            if isequal(f,0), return; end
            exportgraphics(app.UIAxes, fullfile(p,f));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                app.MainApp.childClosed("plot");
            end
            delete(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
             app.updateParamLabels();
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)
             app.CurveColor = app.ColorPicker.Value;

            % 若你想立刻改当前已画曲线的线条颜色
            if ~isempty(app.LastLine) && isvalid(app.LastLine)
                app.LastLine.Color = app.CurveColor;
            end
        
            % ✅ 让数据点颜色立刻同步
            app.applyMarkerStyle();
            
        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            app.syncMarkerSettingsFromUI();
            app.applyMarkerStyle();
        end

        % Value changed function: ShowMarkerCheckBox
        function ShowMarkerCheckBoxValueChanged(app, event)
            app.syncMarkerSettingsFromUI();
            app.applyMarkerStyle();
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            app.syncMarkerSettingsFromUI();
            app.applyMarkerStyle();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '绘图';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图像')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'二次函数', '高斯函数'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '二次函数';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.P1Field = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel_2 = uilabel(app.UIFigure);
            app.EditFieldLabel_2.Text = 'Edit Field';
            app.P2Field = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel_3 = uilabel(app.UIFigure);
            app.EditFieldLabel_3.Text = 'Edit Field';
            app.P3Field = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = '绘图';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = '清空';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = '导出';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x 最小值';
            app.XminField = uieditfield(app.UIFigure, 'numeric');
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x 最大值';
            app.XmaxField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '采样点数';
            app.NField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '线条颜色';
            app.ColorPicker = uicolorpicker(app.UIFigure);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '数据点形状';
            app.MarkerDropDown = uidropdown(app.UIFigure);
            app.MarkerDropDown.Items = {'无', 'o 圆形', 's 方形', '^ 上三角', 'v 下三角', '< 左三角', '> 右三角', 'd 菱形', 'p 五角星', 'h 六角星', '* 星号', 'x 叉号'};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Value = '无';
            app.ShowMarkerCheckBox = uicheckbox(app.UIFigure);
            app.ShowMarkerCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMarkerCheckBoxValueChanged, true);
            app.ShowMarkerCheckBox.Text = '是否显示数据点';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '数据点大小';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '函数参数控制';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '绘图控制';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end